classdef pygellan_stitch_hydrogel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        loadlistMenu                    matlab.ui.container.Menu
        MessageEditField                matlab.ui.control.EditField
        MessageEditFieldLabel           matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        detectTab                       matlab.ui.container.Tab
        ListBox                         matlab.ui.control.ListBox
        ListBoxLabel                    matlab.ui.control.Label
        imflatfield_sigmaEditField      matlab.ui.control.NumericEditField
        imflatfield_sigmaEditFieldLabel  matlab.ui.control.Label
        overlapEditField                matlab.ui.control.NumericEditField
        overlapEditFieldLabel           matlab.ui.control.Label
        imadjustEditField               matlab.ui.control.NumericEditField
        imadjustEditFieldLabel          matlab.ui.control.Label
        edgeEditField                   matlab.ui.control.NumericEditField
        edgeEditFieldLabel              matlab.ui.control.Label
        metricEditField                 matlab.ui.control.NumericEditField
        metricEditFieldLabel            matlab.ui.control.Label
        sensitivityEditField            matlab.ui.control.NumericEditField
        sensitivityEditFieldLabel       matlab.ui.control.Label
        filenameEditField               matlab.ui.control.EditField
        filenameEditFieldLabel          matlab.ui.control.Label
        magellanfilepathEditField       matlab.ui.control.EditField
        magellanfilepathEditFieldLabel  matlab.ui.control.Label
        submitButton                    matlab.ui.control.Button
        openmagellanButton              matlab.ui.control.StateButton
        size_range_maxEditFieldLabel    matlab.ui.control.Label
        size_range_minEditFieldLabel    matlab.ui.control.Label
        size_range_maxEditField         matlab.ui.control.NumericEditField
        size_range_minEditField         matlab.ui.control.NumericEditField
        filterTab                       matlab.ui.container.Tab
        imagefcsButton                  matlab.ui.control.Button
        fcsfilesListBox                 matlab.ui.control.ListBox
        fcsfilesListBoxLabel            matlab.ui.control.Label
        UITable3                        matlab.ui.control.Table
        gelfcstable                     matlab.ui.control.Table
        checkpairsoffcsandxmlButton     matlab.ui.control.Button
        MachinelearningButton           matlab.ui.control.Button
        GateListListBox                 matlab.ui.control.ListBox
        GateListListBoxLabel            matlab.ui.control.Label
        fcsfileLabel                    matlab.ui.control.Label
        flowjoxmlButton                 matlab.ui.control.Button
        stitchimagesListBox             matlab.ui.control.ListBox
        stitchimagesListBoxLabel        matlab.ui.control.Label
        flowjoxmlLabel                  matlab.ui.control.Label
        iddecodeTab                     matlab.ui.container.Tab
        modelLabel                      matlab.ui.control.Label
        PredictIDButton                 matlab.ui.control.Button
        UITable2                        matlab.ui.control.Table
        CreatemodelButton               matlab.ui.control.Button
        ListoffcsButton                 matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        stitch;%stitch_532=evalin('base','stitch_532');
        data_path;%data_path=evalin('base','data_path');
        pygellan;%pygellan=evalin('base','pygellan');
        parameter;
        parameter_file;
        fcs_data;
        fcs_bool;
        fcs_hdr;
    end
    methods (Access=public)
        function stitch_image(app,data_path)
            if ~isequal(data_path,0)
                %filename=extractAfter(data_path,max(strfind(data_path,pathsep)));
                app.parameter.sigma=app.imflatfield_sigmaEditField.Value;

                % Change the status lamp to red.
                app.StatusLamp.Color = 'red';
                % Update the message field
                app.MessageEditField.Value = 'Stitch is now processing....';

                %
                imagedata=dir(fullfile(data_path,'*.tiff'));
                    [app.stitch,app.pygellan]=...
                        import_main(data_path,app.parameter.sigma,imagedata);
                    app.MessageEditField.Value = 'Stitch is done! Please push the submit button.';
                if isempty(imagedata)==0
                    load_stitched_tiff(app,data_path);
                    app.pygellan.iz_max=1;
                    app.MessageEditField.Value = 'Loaded stitched tiff files.';
                end
                stitchfield=fieldnames(app.stitch);
                % creating stack image
                index=contains(stitchfield,"epi_");
                stackimage=[];
                for icnt=1:length(stitchfield)
                    if index(icnt);stackimage=cat(3,stackimage,...
                            app.stitch.(stitchfield{icnt}));end
                end
                app.stitch.epistack=imflatfield(max(stackimage,[],3),app.parameter.sigma);
                figure(1);
                imshow(imadjust(app.stitch.epistack))
                % creating imflat
                app.stitch.BFflat=imflatfield(app.stitch.BF,app.parameter.sigma);
            end
            % Change the status lamp to green.
            app.StatusLamp.Color = 'green';
        end
        function load_stitched_tiff(app,data_path)
            imagedata=dir(fullfile(data_path,'*.tiff'));
            if isempty(imagedata)==0
                app.stitchimagesListBox.Items={imagedata.name};
                app.parameter.imagedata.path=data_path;
                imagedata_size=size(imagedata);
                for icnt=1:imagedata_size(1)
                    [~,channel,~]=fileparts(imagedata(icnt).name);
                    app.stitch.(channel)=imread(fullfile(imagedata(icnt).folder,imagedata(icnt).name));
                end
            end
            fcsdata=dir(fullfile(data_path,'*.fcs'));
            if isempty(fcsdata)==0
                app.fcsfileLabel.Text=fcsdata(1).name;
                app.fcsfilesListBox.Items={fcsdata.name};
            end
        end
        function [filtered_alldata,beads,varindex,gateindex]=load_integrated_data(app)
            filtered_alldata=readtable(fullfile(app.parameter.Listoffcs_datapath,"integrated.csv"),'ReadRowNames',true);
            beads.name = filtered_alldata.Properties.RowNames;
            beads.id =  cellfun(@(str) str(1:4), beads.name, 'UniformOutput', false);
            var.names=filtered_alldata.Properties.VariableNames;
            varindex=contains(var.names,'integrated');
            gateindex=contains(var.names,'ID');
            %beads.batch =  cellfun(@(str) str(6), beads.name, 'UniformOutput', false);
            app.parameter.integrated.data=filtered_alldata;
            app.parameter.integrated.beads=beads;
            app.parameter.integrated.var=var.names;

        end
        function check_filter(app,selection)
            %fcsfile = string(app.gelfcstable.Data(app.gelfcstable.Selection(1)));
            %data_path=app.parameter.data_path;
            %fcsfilepath=dir(fullfile(data_path,'**',fcsfile));
            [~,name,~]=fileparts(app.stitchimagesListBox.Value);
            %imshow(imadjust(app.stitch.(name)));hold on
            fcs_data=app.fcs_data;
            fcs_bool=app.fcs_bool;

            %% mean intensit
            pad=4;
            I=app.stitch.(name);
            img_size=size(I);
            centers=int16(fcs_data(selection(1),1:2));
            radii=fcs_data(selection(1),4);
            [rowstart,rowend,colstart,colend]=...
                frame_create_sub_image(centers(:,2),centers(:,1),...
                radii,pad,img_size(1),img_size(2));
            subimage=I(rowstart:rowend,colstart:colend);
            h2=figure(1);set(h2,'KeyPressFcn',@KeyPressCb);
            hold off;h2=imshow(imadjust(subimage));hold on
            %%
            if fcs_bool(selection(1))
                h2=viscircles([fcs_data(selection(1),1)-colstart,fcs_data(selection(1),2)-rowstart],fcs_data(selection(1),4), ...
                    'Color','red','LineWidth',0.1);
            else
                h2=plot(fcs_data(selection(1),1)-colstart,fcs_data(selection(1),2)-rowstart,'xb')
            end
            %app.UITable3.focus
            %UITable3SelectionChanged(app, event)
            function KeyPressCb(~,evnt)
                    %close all
                %fprintf('key pressed: %s\n',evnt.Key);
                if strcmpi(evnt.Key,'n')
                    %app.UITable3.focus
                    app.UITable3.Selection=[selection(1)+1,selection(2)];
                    check_filter(app,[selection(1)+1,selection(2)]);
                elseif strcmpi(evnt.Key,'b')
                    app.UITable3.Selection=[selection(1)-1,selection(2)];
                    check_filter(app,[selection(1)-1,selection(2)]);
                else
                    app.fcs_bool(selection(1))=~app.fcs_bool(selection(1));
                    app.UITable3.Data(selection(1),4)=app.fcs_bool(selection(1));
                    check_filter(app,[selection(1),selection(2)]);
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: openmagellanButton
        function openmagellanButtonValueChanged(app, event)
            close all;
            % Change the color of StatusLamp to yellow.
            
            %value = app.openmagellanButton.Value;

                data_path=uigetdir(app.magellanfilepathEditField.Value);%(app.data_path);
                %app.data_path=data_path;
                if  ~isequal(data_path,0)
                    app.StatusLamp.Color = 'yellow';
                    app.MessageEditField.Value = 'Ready to start the stitching.';
                    app.magellanfilepathEditField.Value=data_path;
                    [~,filename,~]=fileparts(data_path);
                    app.filenameEditField.Value=filename;
                    app.parameter_file=fullfile(data_path,'parameter.mat'); 
                    file_exist=exist(app.parameter_file,'file');
                    if file_exist>0
                        % load parameter.mat if exits
                        load(app.parameter_file);
                        app.parameter=parameter;
                        app.sensitivityEditField.Value=parameter.sensitivity;
                        app.edgeEditField.Value=parameter.edgethreshold;
                        app.size_range_minEditField.Value=parameter.sizerange(1);
                        app.size_range_maxEditField.Value=parameter.sizerange(2);
                        app.metricEditField.Value=parameter.metricthreshold;
                        app.imadjustEditField.Value=parameter.imadjust;
                        app.overlapEditField.Value=parameter.radii_overlap;
                        app.imflatfield_sigmaEditField.Value=app.parameter.sigma;
                    end
                    stitch_image(app,data_path);
                end

        end

        % Button pushed function: submitButton
        function submitButtonPushed(app, event)

                close all;
                app.parameter.sensitivity=double(app.sensitivityEditField.Value);
                app.parameter.edgethreshold=app.edgeEditField.Value;
                app.parameter.sizerange=[app.size_range_minEditField.Value app.size_range_maxEditField.Value];
                app.parameter.metricthreshold=double(app.metricEditField.Value);
                app.parameter.imadjust=double(app.imadjustEditField.Value);
                app.parameter.radii_overlap=double(app.overlapEditField.Value);
                app.parameter.sigma=app.imflatfield_sigmaEditField.Value;
            if numel(app.ListBox.Items)==0
                app.parameter.fcsname=app.filenameEditField.Value;
                %app.parameter_file=fullfile(data_path,'parameter.mat'); 
                data_path=app.magellanfilepathEditField.Value;
                app.parameter.data_path=data_path;
                app.parameter_file=fullfile(data_path,'parameter.mat'); 
                %app.parameter.iz_max=int16(app.pygellan.iz_max);
                app.parameter.iz_max=1;
            % Change the status lamp to red.
                app.StatusLamp.Color = 'red';
                app.MessageEditField.Value = 'Detection of hydrigel is now processing....';
                app.parameter.data_path=app.magellanfilepathEditField.Value;
                parameter=app.parameter;
                save(app.parameter_file,'parameter');
                hydrogel_main1_detection(app.parameter,app.stitch,app.pygellan);
            else
                for icnt=1:numel(app.ListBox.Items)
                    filename=char(app.ListBox.Items(icnt));
                    app.parameter.data_path=char(fullfile(app.magellanfilepathEditField.Value,filename));
                    app.parameter.fcsname=filename;
                    % Change the color of StatusLamp to yellow.
                    app.StatusLamp.Color = 'yellow';
                    app.MessageEditField.Value = 'Ready to start the stitching.';

                    %value = app.openmagellanButton.Value;
                    data_path=app.parameter.data_path;
                    app.parameter_file=fullfile(data_path,'parameter.mat'); 
                    stitch_image(app,data_path);
                    app.parameter.iz_max=int16(app.pygellan.iz_max);

                    % Change the status lamp to red.
                    app.StatusLamp.Color = 'red';
                    app.MessageEditField.Value = 'Detection of hydrigel is now processing....';
                    parameter=app.parameter;
                    save(app.parameter_file,'parameter');
                    hydrogel_main1_detection(app.parameter,app.stitch,app.pygellan);

                end
                app.ListBox.Items={};

            end
                app.StatusLamp.Color = 'green';
                app.MessageEditField.Value = 'Detection is done! fcs file is exported.';
        end

        % Menu selected function: loadlistMenu
        function loadlistMenuSelected(app, event)
            [data_path,path]=uigetfile('*.txt','',app.magellanfilepathEditField.Value);
            if ~isequal(data_path,0)
                dirlist = textread(fullfile(path,data_path),'%s');
                app.magellanfilepathEditField.Value=path;
                app.ListBox.Items=dirlist;
            end

        end

        % Button pushed function: flowjoxmlButton
        function flowjoxmlButtonPushed(app, event)
            addpath(genpath('MatlabCytofUtilities'));
             [data_path,path]=uigetfile('*.xml','',app.magellanfilepathEditField.Value);
            if ~isequal(data_path,0)
                app.flowjoxmlLabel.Text=strcat(path,data_path);
                xml_filename=app.flowjoxmlLabel.Text;
                app.parameter.xml_filename=xml_filename;
                obj=gatingML(xml_filename); %create gatingML object
                gateNames=fieldnames(obj.gates);
                app.GateListListBox.Items=gateNames;
            end
        end

        % Button pushed function: imagefcsButton
        function imagefcsButtonPushed(app, event)
                close all;
            %value = app.openmagellanButton.Value;
                data_path=uigetdir(app.magellanfilepathEditField.Value);%(app.data_path);
                if ~isequal(data_path,0)
                    app.magellanfilepathEditField.Value=data_path;
                    [~,filename,~]=fileparts(data_path);
                    app.filenameEditField.Value=filename;                    
                    app.parameter_file=fullfile(data_path,'parameter.mat'); 
                    load_stitched_tiff(app,data_path);
                end
                %app.data_path=data_path;
                
        end

        % Value changed function: stitchimagesListBox
        function stitchimagesListBoxValueChanged(app, event)
            value = app.stitchimagesListBox.Value;
            %msgbox(fullfile(app.parameter.imagedata.path,value));
            image=imread(fullfile(app.parameter.imagedata.path,value));
            figure(1)
            imshow(imadjust(imflatfield(image,app.parameter.sigma)));
            fcsfile=fullfile(app.parameter.imagedata.path,app.fcsfileLabel.Text);
             xml_filename=app.flowjoxmlLabel.Text;
            if strcmp(xml_filename(1),'flowjo.xml')==0
                gate=app.GateListListBox.Value;
                if isempty(gate)==0;[~]=main2_flowjo(gate,fcsfile,xml_filename);end
%             else
%                 msgbox('select gate.xml file')
            end
        end

        % Button pushed function: MachinelearningButton
        function MachinelearningButtonPushed(app, event)
%             value = app.stitchimagesListBox.Value;
             [~,name,~]=fileparts(app.stitchimagesListBox.Value);

            if isempty(name)==0
                %msgbox(fullfile(app.parameter.imagedata.path,value));

                image=app.stitch.(name);%imread(fullfile(app.parameter.imagedata.path,value));
                figure(1)
                imshow(imadjust(image));hold on
                fcs_data=app.fcs_data;
                fcs_bool=app.fcs_bool;
                data_path=app.parameter.data_path;
                app.parameter.gelmodelpath=fullfile(data_path,"gelmodel");
%                xml_filename=app.flowjoxmlLabel.Text;
%                 if strcmp(xml_filename(1),'flowjo.xml')==0
%                     gate=app.GateListListBox.Value;
                    if isfile(strcat(app.parameter.gelmodelpath,".mat"))==1
                        fcsfile=fullfile(app.parameter.imagedata.path,...
                            app.fcsfileLabel.Text);
                        [folder,name,ext]=fileparts(fcsfile);
                        [~,fcs_hdr,fcs_data]=fca_readfcs(fcsfile);
                        labels = logical(predict(app.parameter.GelMdl,fcs_data));
                        error_fcs_data=logical(~labels);
                        plot(fcs_data(error_fcs_data,1),fcs_data(error_fcs_data,2),'xb'); %scatter plot of data
                        viscircles(fcs_data(labels,1:2),fcs_data(labels,4),'Color','red','LineWidth',0.1); %scatter plot of data
                        hold off
                        
                        parlist=struct2cell(fcs_hdr.par);
                        index=find(strcmp(parlist(1,1,:),'bool'));
                        fcs_data(:,index)=labels;
%                          fcs_hdr=flowjo_create_fcs_metadata(fcs_hdr.starttime,fcs_hdr.stoptime,...
%                              fcs_hdr.project,fcs_hdr.experiment,fcs_hdr.cells,...
%                               fcs_hdr.filename,fcs_hdr.filepath,string(fcs_hdr.par.name), ...
%                              fcs_hdr.TotalEvents);
                        marker_names={fcs_hdr.par.name};
                        channel_names={fcs_hdr.par.name};
                        fca_writefcs(char(fullfile(folder,strcat(name,ext,"ML"))),...
                            fcs_data, marker_names,channel_names,fcs_hdr)
                        %[~]=main2_flowjo('machine_learning',fcsfile,xml_filename);
                        %parameter=app.parameter;
                        %save(app.parameter_file,'parameter');
                    else
%                         if  exist(strcat(app.parameter.gelmodelpath,".mat"))==2
%                             load(app.parameter.gelmodelpath);
%                             app.parameter.GelMdl=GelMdl;
%                             %app.modelLabel.Text=app.parameter.codemodelpath;
%                         else

                            rng('default')
                            t = templateTree('Reproducible',true);

                            GelMdl = fitcensemble(fcs_data(1:app.UITable3.Selection(1),:),fcs_bool(1:app.UITable3.Selection(1)),'OptimizeHyperparameters','auto','Learners',t, ...
                                'HyperparameterOptimizationOptions',struct('AcquisitionFunctionName','expected-improvement-plus'))

                            save(app.parameter.gelmodelpath,'GelMdl')

                            %executed=system(['Rscript --vanilla /home/watson/public/shintaku/github/beads_qc/matlab_fcs_main.R ', data_path]);
%                         end
                        predictlabels = predict(GelMdl,fcs_data);
                        C=confusionmat(fcs_bool,predictlabels);
                        label=unique(fcs_bool);
                        confusionchart(C,label);
                        app.parameter.Listoffcs_datapath=data_path;
                        app.StatusLamp.Color = 'green';
                        app.MessageEditField.Value = 'Loaded machine learning model';

                        msgbox('create a model')
                        app.parameter.GelMdl=GelMdl;
                    end
            else
                msgbox('select image in the list box')
            end
        end

        % Button pushed function: ListoffcsButton
        function ListoffcsButtonPushed(app, event)
            
            data_path=uigetdir(app.magellanfilepathEditField.Value);%(app.data_path);
            %Rpath='/home/samba/public/shintaku/github/beads_qc/';
            if ~isequal(data_path,0)
                app.StatusLamp.Color = 'red';
                app.MessageEditField.Value = 'Read fcs files and batch correct';
                list=dir(fullfile(data_path, '**', '*.fcs'));
                pathlist=string(cat(1,list(:).folder));
                filelist=string(cat(1,list(:).name));
                split_id=split(filelist,'_');
                %app.UITable.Data=[pathlist,filelist,split_id(:,2)];

                index=find(contains(split_id(:,2),"MX")~=1);
                % if strcmp(split_id(:,2),'IDMX')==0
                     app.UITable.Data=[pathlist(index),filelist(index),split_id(index,2)];
              %   else
                index=find(contains(split_id(:,2),"MX"));
                     app.UITable2.Data=[pathlist(index),filelist(index),split_id(index,2)];
               %  end
                app.parameter.codemodelpath=fullfile(data_path,"codemodel");
                if  exist(strcat(app.parameter.codemodelpath,".mat"))==2
                    load(app.parameter.codemodelpath);
                    app.parameter.CodeMdl=CodeMdl;
                    app.modelLabel.Text=app.parameter.codemodelpath;
                end
                executed=system(['Rscript --vanilla /home/samba/public/shintaku/github/beads_qc/matlab_fcs_main.R ', data_path]);
                if executed ==0;app.parameter.Listoffcs_datapath=data_path;
                app.StatusLamp.Color = 'green';
                app.MessageEditField.Value = 'Finish batch correction';end
            end
        end

        % Button pushed function: CreatemodelButton
        function CreatemodelButtonPushed(app, event)
            
    [filtered_alldata,beads,varindex,gateindex]=load_integrated_data(app);

        if any(gateindex)
        %bool=contains(table2cell(filtered_alldata(:,gateindex)),'TRUE');
            bool=contains(table2cell(filtered_alldata(:,gateindex)),'TRUE') & ~contains(beads.id,'MX');
        else
            bool=~contains(beads.id,'MX');
        end
             rng('default')
             t = templateTree('Reproducible',true);
             CodeMdl = fitcensemble(filtered_alldata(bool(:,1),varindex),...
                 beads.id(bool(:,1)),'OptimizeHyperparameters','auto','Learners',t, ...
                 'HyperparameterOptimizationOptions',...
                 struct('AcquisitionFunctionName','expected-improvement-plus'));
            app.parameter.CodeMdl=CodeMdl;
            parameter=app.parameter;
            save(parameter.codemodelpath,'CodeMdl')
            app.modelLabel.Text=app.parameter.codemodelpath;
           
            code.original=beads.id(bool(:,1));
            %code.original=strrep(code.original,"MX","ID");
            code.labels = string(predict(app.parameter.CodeMdl,filtered_alldata(bool(:,1),varindex)));
            %[coeff,score,latent] =pca(filtered_alldata(:,11:16));
            C=confusionmat(code.original,code.labels);
            label=unique(code.original);
            confusionchart(C,label);
        end

        % Button pushed function: PredictIDButton
        function PredictIDButtonPushed(app, event)
    [filtered_alldata,beads,varindex,gateindex]=load_integrated_data(app);

        if any(gateindex)
        %bool=contains(table2cell(filtered_alldata(:,gateindex)),'TRUE');
            bool=contains(table2cell(filtered_alldata(:,gateindex)),'TRUE') & ~contains(beads.id,'MX');
        else
            bool=~contains(beads.id,'MX');
        end
            CodeMdl=app.parameter.CodeMdl;
            code.original=beads.id(~bool(:,1));
            code.original=strrep(code.original,"MX","ID");
            code.labels = string(predict(app.parameter.CodeMdl,filtered_alldata(~bool(:,1),varindex)));
            %[coeff,score,latent] =pca(filtered_alldata(:,11:16));
            C=confusionmat(code.original,code.labels,"Order",unique(code.labels));
            label=unique(code.labels);
            confusionchart(C,label);
        end

        % Selection changed function: UITable2
        function UITable2SelectionChanged(app, event)
            selection = app.UITable2.Selection;
            Imagelist=app.UITable2.Data;
            list=dir(fullfile(string(app.parameter.Listoffcs_datapath), '**',string(Imagelist(selection(1),1)) ,'Outline_Cells_.tiff'));
            imdata=imread(fullfile(list.folder,list.name));
            imshow(imadjust(imdata));title(Imagelist(selection(1),1))
        end

        % Cell edit callback: UITable2
        function UITable2CellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
        end

        % Button pushed function: checkpairsoffcsandxmlButton
        function checkpairsoffcsandxmlButtonPushed(app, event)
            data_path=uigetdir(app.magellanfilepathEditField.Value);%(app.data_path);
            %Rpath='/home/watson/public/shintaku/github/beads_qc/';
            if ~isequal(data_path,0)
                app.parameter.data_path=data_path;
                app.StatusLamp.Color = 'red';
                app.MessageEditField.Value = 'Read fcs files and learn empty/gel containing wells';
                fcslist=dir(fullfile(data_path, '**', '*.fcs'));
                xmllist=dir(fullfile(data_path, '**', '*.xml'));
                app.gelfcstable.Data=[transpose({fcslist(:).name}),...
                    transpose({xmllist(:).name})];
                %num_of_fcs=length(fcslist);
                %signal_bool_all=[];
                %fcs_data_all=[];
                %for icnt=1:num_of_fcs
                    %fcsfile=fullfile(fcslist(icnt).folder,fcslist(icnt).name);
                    %xml_filename=fullfile(xmllist(icnt).folder,xmllist(icnt).name);
                    %obj=gatingML(xml_filename); %create gatingML object
                    %gateNames=fieldnames(obj.gates);
                    %[~,fcs_hdr,fcs_data]=fca_readfcs(fcsfile);
                    %obj=obj.load_fcs_file(fcs_data,fcs_hdr); %associate the fcs data with these gates
                    %gate=string(gateNames(1));
                    %obj=obj.apply_gate(gate);
                    %num_cells=nnz(obj.gates.(gate).inGate);
                    %Signal_bool=obj.gates.(gate).inGate;
                    %signal_bool_all=cat(1,signal_bool_all,Signal_bool);
                    %fcs_data_all=cat(1,fcs_data_all,fcs_data);
                %end
            end       
        end

        % Selection changed function: gelfcstable
        function gelfcstableSelectionChanged(app, event)
            selection = app.gelfcstable.Selection;
            
            if ~isequal(selection(1),0)
                data_path=app.parameter.data_path;
                app.StatusLamp.Color = 'red';
                app.MessageEditField.Value = 'Read fcs files and learn empty/gel containing wells';
                fcslist=dir(fullfile(data_path, '**', '*.fcs'));
                xmllist=dir(fullfile(data_path, '**', '*.xml'));
                app.gelfcstable.Data=[transpose({fcslist(:).name}),...
                    transpose({xmllist(:).name})];
                num_of_fcs=length(fcslist);
                %signal_bool_all=[];
                %fcs_data_all=[];
                %for icnt=1:num_of_fcs
                    fcsfile=fullfile(fcslist(selection(1)).folder,fcslist(selection(1)).name);
                    xml_filename=fullfile(xmllist(selection(1)).folder,xmllist(selection(1)).name);
                    obj=gatingML(xml_filename); %create gatingML object
                    gateNames=fieldnames(obj.gates);
                    [~,fcs_hdr,fcs_data]=fca_readfcs(fcsfile);
                    obj=obj.load_fcs_file(fcs_data,fcs_hdr); %associate the fcs data with these gates
                    gate=string(gateNames(1));
                    obj=obj.apply_gate(gate);
                    %num_cells=nnz(obj.gates.(gate).inGate);
                    Signal_bool=obj.gates.(gate).inGate;
                     app.UITable3.Data=[fcs_data(:,find(contains({fcs_hdr.par.name},'id'))), ...
                         fcs_data(:,find(contains({fcs_hdr.par.name},'center_x'))),...
                         fcs_data(:,find(contains({fcs_hdr.par.name},'center_y'))),...
                         Signal_bool];
                    %signal_bool_all=cat(1,signal_bool_all,Signal_bool);
                    %fcs_data_all=cat(1,fcs_data_all,fcs_data);
                    app.fcs_data=fcs_data;
                    app.fcs_hdr=fcs_hdr;
                    app.fcs_bool=Signal_bool;
                %end
            end                
        end

        % Selection changed function: UITable3
        function UITable3SelectionChanged(app, event)

            selection = app.UITable3.Selection;
            check_filter(app,selection)
        end

        % Value changed function: fcsfilesListBox
        function fcsfilesListBoxValueChanged(app, event)
            value = app.fcsfilesListBox.Value;
            app.fcsfileLabel.Text=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.loadlistMenu = uimenu(app.Menu);
            app.loadlistMenu.MenuSelectedFcn = createCallbackFcn(app, @loadlistMenuSelected, true);
            app.loadlistMenu.Text = 'load list';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.detectTab = uitab(app.TabGroup);
            app.detectTab.Title = 'detect';
            app.size_range_minEditField = uieditfield(app.detectTab, 'numeric');
            app.size_range_minEditField.Value = 10;
            app.size_range_maxEditField = uieditfield(app.detectTab, 'numeric');
            app.size_range_maxEditField.Value = 30;
            app.size_range_minEditFieldLabel = uilabel(app.detectTab);
            app.size_range_minEditFieldLabel.Text = 'size_range_min';
            app.size_range_maxEditFieldLabel = uilabel(app.detectTab);
            app.size_range_maxEditFieldLabel.Text = 'size_range_max';
            app.openmagellanButton = uibutton(app.detectTab, 'state');
            app.openmagellanButton.ValueChangedFcn = createCallbackFcn(app, @openmagellanButtonValueChanged, true);
            app.openmagellanButton.Text = 'open magellan';
            app.submitButton = uibutton(app.detectTab, 'push');
            app.submitButton.ButtonPushedFcn = createCallbackFcn(app, @submitButtonPushed, true);
            app.submitButton.Text = 'submit';
            app.magellanfilepathEditFieldLabel = uilabel(app.detectTab);
            app.magellanfilepathEditFieldLabel.Text = 'magellan file path';
            app.magellanfilepathEditField = uieditfield(app.detectTab, 'text');
            app.filenameEditFieldLabel = uilabel(app.detectTab);
            app.filenameEditFieldLabel.Text = 'file name';
            app.filenameEditField = uieditfield(app.detectTab, 'text');
            app.sensitivityEditFieldLabel = uilabel(app.detectTab);
            app.sensitivityEditFieldLabel.Text = 'sensitivity';
            app.sensitivityEditField = uieditfield(app.detectTab, 'numeric');
            app.sensitivityEditField.Value = 0.9;
            app.metricEditFieldLabel = uilabel(app.detectTab);
            app.metricEditFieldLabel.Text = 'metric';
            app.metricEditField = uieditfield(app.detectTab, 'numeric');
            app.metricEditField.Value = 0.01;
            app.edgeEditFieldLabel = uilabel(app.detectTab);
            app.edgeEditFieldLabel.Text = 'edge';
            app.edgeEditField = uieditfield(app.detectTab, 'numeric');
            app.edgeEditField.Value = 0.1;
            app.imadjustEditFieldLabel = uilabel(app.detectTab);
            app.imadjustEditFieldLabel.Text = 'imadjust';
            app.imadjustEditField = uieditfield(app.detectTab, 'numeric');
            app.imadjustEditField.Value = 0.06;
            app.overlapEditFieldLabel = uilabel(app.detectTab);
            app.overlapEditFieldLabel.Text = 'overlap';
            app.overlapEditField = uieditfield(app.detectTab, 'numeric');
            app.overlapEditField.Value = 0.5;
            app.imflatfield_sigmaEditFieldLabel = uilabel(app.detectTab);
            app.imflatfield_sigmaEditFieldLabel.Text = 'imflatfield_sigma';
            app.imflatfield_sigmaEditField = uieditfield(app.detectTab, 'numeric');
            app.imflatfield_sigmaEditField.Value = 80;
            app.ListBoxLabel = uilabel(app.detectTab);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.detectTab);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.filterTab = uitab(app.TabGroup);
            app.filterTab.Title = 'filter';
            app.flowjoxmlLabel = uilabel(app.filterTab);
            app.flowjoxmlLabel.Text = {'flowjo.xml'; ''};
            app.stitchimagesListBoxLabel = uilabel(app.filterTab);
            app.stitchimagesListBoxLabel.Text = 'stitch images';
            app.stitchimagesListBox = uilistbox(app.filterTab);
            app.stitchimagesListBox.Items = {};
            app.stitchimagesListBox.ValueChangedFcn = createCallbackFcn(app, @stitchimagesListBoxValueChanged, true);
            app.stitchimagesListBox.Value = {};
            app.flowjoxmlButton = uibutton(app.filterTab, 'push');
            app.flowjoxmlButton.ButtonPushedFcn = createCallbackFcn(app, @flowjoxmlButtonPushed, true);
            app.flowjoxmlButton.Text = 'flowjo.xml';
            app.fcsfileLabel = uilabel(app.filterTab);
            app.fcsfileLabel.Text = 'fcs file';
            app.GateListListBoxLabel = uilabel(app.filterTab);
            app.GateListListBoxLabel.Text = 'GateList';
            app.GateListListBox = uilistbox(app.filterTab);
            app.GateListListBox.Items = {};
            app.GateListListBox.Value = {};
            app.MachinelearningButton = uibutton(app.filterTab, 'push');
            app.MachinelearningButton.ButtonPushedFcn = createCallbackFcn(app, @MachinelearningButtonPushed, true);
            app.MachinelearningButton.Text = 'Machine learning';
            app.checkpairsoffcsandxmlButton = uibutton(app.filterTab, 'push');
            app.checkpairsoffcsandxmlButton.ButtonPushedFcn = createCallbackFcn(app, @checkpairsoffcsandxmlButtonPushed, true);
            app.checkpairsoffcsandxmlButton.Text = 'check pairs of fcs and xml';
            app.gelfcstable = uitable(app.filterTab);
            app.gelfcstable.ColumnName = {'fcs'; 'xml'};
            app.gelfcstable.RowName = {};
            app.gelfcstable.SelectionChangedFcn = createCallbackFcn(app, @gelfcstableSelectionChanged, true);
            app.UITable3 = uitable(app.filterTab);
            app.UITable3.ColumnName = {'id'; 'x'; 'y'; 'bool'};
            app.UITable3.RowName = {};
            app.UITable3.SelectionChangedFcn = createCallbackFcn(app, @UITable3SelectionChanged, true);
            app.fcsfilesListBoxLabel = uilabel(app.filterTab);
            app.fcsfilesListBoxLabel.Text = 'fcs files';
            app.fcsfilesListBox = uilistbox(app.filterTab);
            app.fcsfilesListBox.ValueChangedFcn = createCallbackFcn(app, @fcsfilesListBoxValueChanged, true);
            app.imagefcsButton = uibutton(app.filterTab, 'push');
            app.imagefcsButton.ButtonPushedFcn = createCallbackFcn(app, @imagefcsButtonPushed, true);
            app.imagefcsButton.Text = 'image & fcs';
            app.iddecodeTab = uitab(app.TabGroup);
            app.iddecodeTab.Title = 'id decode';
            app.UITable = uitable(app.iddecodeTab);
            app.UITable.ColumnName = {'path'; 'file'; 'channel'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false false true];
            app.ListoffcsButton = uibutton(app.iddecodeTab, 'push');
            app.ListoffcsButton.ButtonPushedFcn = createCallbackFcn(app, @ListoffcsButtonPushed, true);
            app.ListoffcsButton.Text = 'List of fcs';
            app.CreatemodelButton = uibutton(app.iddecodeTab, 'push');
            app.CreatemodelButton.ButtonPushedFcn = createCallbackFcn(app, @CreatemodelButtonPushed, true);
            app.CreatemodelButton.Text = 'Create model';
            app.UITable2 = uitable(app.iddecodeTab);
            app.UITable2.ColumnName = {'path'; 'file'; 'channel'};
            app.UITable2.RowName = {};
            app.UITable2.CellEditCallback = createCallbackFcn(app, @UITable2CellEdit, true);
            app.UITable2.SelectionChangedFcn = createCallbackFcn(app, @UITable2SelectionChanged, true);
            app.PredictIDButton = uibutton(app.iddecodeTab, 'push');
            app.PredictIDButton.ButtonPushedFcn = createCallbackFcn(app, @PredictIDButtonPushed, true);
            app.PredictIDButton.Text = 'Predict ID';
            app.modelLabel = uilabel(app.iddecodeTab);
            app.modelLabel.Text = 'model';
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.MessageEditFieldLabel = uilabel(app.UIFigure);
            app.MessageEditFieldLabel.Text = 'Message';
            app.MessageEditField = uieditfield(app.UIFigure, 'text');
            app.MessageEditField.Value = 'Please open magellan file.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pygellan_stitch_hydrogel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end